classdef static < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Static_force             matlab.ui.Figure
        outputButton             matlab.ui.control.Button
        endforceXEditFieldLabel  matlab.ui.control.Label
        endforceXEditField       matlab.ui.control.NumericEditField
        endforceYEditFieldLabel  matlab.ui.control.Label
        endforceYEditField       matlab.ui.control.NumericEditField
        endforceZEditFieldLabel  matlab.ui.control.Label
        endforceZEditField       matlab.ui.control.NumericEditField
        theta1EditFieldLabel     matlab.ui.control.Label
        theta1EditField          matlab.ui.control.NumericEditField
        theta2EditFieldLabel     matlab.ui.control.Label
        theta2EditField          matlab.ui.control.NumericEditField
        theta3EditFieldLabel     matlab.ui.control.Label
        theta3EditField          matlab.ui.control.NumericEditField
        theta4EditFieldLabel     matlab.ui.control.Label
        theta4EditField          matlab.ui.control.NumericEditField
        theta5EditFieldLabel     matlab.ui.control.Label
        theta5EditField          matlab.ui.control.NumericEditField
        theta6EditFieldLabel     matlab.ui.control.Label
        theta6EditField          matlab.ui.control.NumericEditField
        endforceNLabel           matlab.ui.control.Label
        initialjonitangleLabel   matlab.ui.control.Label
        toqueineachjointLabel    matlab.ui.control.Label
        theta1EditField_2Label   matlab.ui.control.Label
        theta1T                  matlab.ui.control.NumericEditField
        theta2EditField_2Label   matlab.ui.control.Label
        theta2T                  matlab.ui.control.NumericEditField
        theta3EditField_2Label   matlab.ui.control.Label
        theta3T                  matlab.ui.control.NumericEditField
        theta4EditField_2Label   matlab.ui.control.Label
        theta4T                  matlab.ui.control.NumericEditField
        theta5EditField_2Label   matlab.ui.control.Label
        theta5T                  matlab.ui.control.NumericEditField
        theta6EditField_2Label   matlab.ui.control.Label
        theta6T                  matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: outputButton
        function outputButtonPushed(app, event)
            Fx=app.endforceXEditField.Value;
            Fy=app.endforceYEditField.Value;
            Fz=app.endforceZEditField.Value;
            
            th1=app.theta1EditField.Value;
            th2=app.theta2EditField.Value;
            th3=app.theta3EditField.Value;
            th4=app.theta4EditField.Value;
            th5=app.theta5EditField.Value;
            th6=app.theta6EditField.Value;
            
            
            F=[Fx;Fy;Fz]; %% F is the force act on the end position
            theta0=[th1;th2;th3;th4;th5;th6];
            theta0=theta0*pi/180;
            [~,~,J0]=Position(theta0);
            tau=J0'*F/1000;   %unit is N*m
            app.theta1T.Value=tau(1);
            app.theta2T.Value=tau(2);
            app.theta3T.Value=tau(3);
            app.theta4T.Value=tau(4);
            app.theta5T.Value=tau(5);
            app.theta6T.Value=tau(6);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Static_force = uifigure('Visible', 'off');
            app.Static_force.Name = 'UI Figure';
            app.outputButton = uibutton(app.Static_force, 'push');
            app.outputButton.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.outputButton.Text = 'output';
            app.endforceXEditFieldLabel = uilabel(app.Static_force);
            app.endforceXEditFieldLabel.Text = 'endforceX';
            app.endforceXEditField = uieditfield(app.Static_force, 'numeric');
            app.endforceXEditField.Value = 100;
            app.endforceYEditFieldLabel = uilabel(app.Static_force);
            app.endforceYEditFieldLabel.Text = 'endforceY';
            app.endforceYEditField = uieditfield(app.Static_force, 'numeric');
            app.endforceYEditField.Value = 100;
            app.endforceZEditFieldLabel = uilabel(app.Static_force);
            app.endforceZEditFieldLabel.Text = 'endforceZ';
            app.endforceZEditField = uieditfield(app.Static_force, 'numeric');
            app.endforceZEditField.Value = 100;
            app.theta1EditFieldLabel = uilabel(app.Static_force);
            app.theta1EditFieldLabel.Text = 'theta1';
            app.theta1EditField = uieditfield(app.Static_force, 'numeric');
            app.theta2EditFieldLabel = uilabel(app.Static_force);
            app.theta2EditFieldLabel.Text = 'theta2';
            app.theta2EditField = uieditfield(app.Static_force, 'numeric');
            app.theta2EditField.Value = -90;
            app.theta3EditFieldLabel = uilabel(app.Static_force);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.Static_force, 'numeric');
            app.theta4EditFieldLabel = uilabel(app.Static_force);
            app.theta4EditFieldLabel.Text = 'theta4';
            app.theta4EditField = uieditfield(app.Static_force, 'numeric');
            app.theta4EditField.Value = -90;
            app.theta5EditFieldLabel = uilabel(app.Static_force);
            app.theta5EditFieldLabel.Text = 'theta5';
            app.theta5EditField = uieditfield(app.Static_force, 'numeric');
            app.theta6EditFieldLabel = uilabel(app.Static_force);
            app.theta6EditFieldLabel.Text = 'theta6';
            app.theta6EditField = uieditfield(app.Static_force, 'numeric');
            app.endforceNLabel = uilabel(app.Static_force);
            app.endforceNLabel.Text = 'endforce  (N)';
            app.initialjonitangleLabel = uilabel(app.Static_force);
            app.initialjonitangleLabel.Text = 'initial jonit angle';
            app.toqueineachjointLabel = uilabel(app.Static_force);
            app.toqueineachjointLabel.Text = 'toque in each joint';
            app.theta1EditField_2Label = uilabel(app.Static_force);
            app.theta1EditField_2Label.Text = 'theta1';
            app.theta1T = uieditfield(app.Static_force, 'numeric');
            app.theta2EditField_2Label = uilabel(app.Static_force);
            app.theta2EditField_2Label.Text = 'theta2';
            app.theta2T = uieditfield(app.Static_force, 'numeric');
            app.theta3EditField_2Label = uilabel(app.Static_force);
            app.theta3EditField_2Label.Text = 'theta3';
            app.theta3T = uieditfield(app.Static_force, 'numeric');
            app.theta4EditField_2Label = uilabel(app.Static_force);
            app.theta4EditField_2Label.Text = 'theta4';
            app.theta4T = uieditfield(app.Static_force, 'numeric');
            app.theta5EditField_2Label = uilabel(app.Static_force);
            app.theta5EditField_2Label.Text = 'theta5';
            app.theta5T = uieditfield(app.Static_force, 'numeric');
            app.theta6EditField_2Label = uilabel(app.Static_force);
            app.theta6EditField_2Label.Text = 'theta6';
            app.theta6T = uieditfield(app.Static_force, 'numeric');
            app.Label = uilabel(app.Static_force);
            app.Label.Text = 'this app can calculate the toque of the each joint when the force of end position and the initial joint angle is imputed  ';
            % Show the figure after all components are created
            app.Static_force.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = static

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Static_force)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Static_force)
        end
    end
end